classdef review_emg_rc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EMGDataTxtEditFieldLabel       matlab.ui.control.Label
        EMGDataTxtEditField            matlab.ui.control.EditField
        EmgDataSelectButton            matlab.ui.control.Button
        MMaxSelectButton               matlab.ui.control.Button
        MMaxExcelEditFieldLabel        matlab.ui.control.Label
        MMaxExcelEditField             matlab.ui.control.EditField
        SessionEditFieldLabel          matlab.ui.control.Label
        SessionEditField               matlab.ui.control.EditField
        MuscleEditFieldLabel           matlab.ui.control.Label
        MuscleEditField                matlab.ui.control.EditField
        MmaxVEditFieldLabel            matlab.ui.control.Label
        MmaxEditField                  matlab.ui.control.NumericEditField
        RCDatapointsCSVEditFieldLabel  matlab.ui.control.Label
        RCDatapointsCSVEditField       matlab.ui.control.EditField
    end


    properties (Access = public)
        params          % parameters of the emg data (sampFreq, pre & postTriggerTime)
        
        emg_data_fig    % figure to display emg & rc data
        
        h_disp_emg_axes % axes to display emg data epoch
        h_emg_line      % handle to emg data line
        h_t_min_line    % handle to vertical line at lower time for finding MEP
        h_t_max_line    % handle to vertical line at upper time for finding MEP
        row_displayed   % the current row being displayed
        
        h_disp_rc_axes  % axes to display rc
        
        h_uitable       % table with rc datapoint info
        
        emg_data        % matrix with emg data. Col 1 = magstim value, col 2-301 = emg data
    end


    methods (Access = private)

        % Button pushed function: MMaxSelectButton
        function MMaxSelectButtonPushed(app, event)
            [fname, pname] = uigetfile('*.xlsx', 'Pick M-max Excel File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.MMaxExcelEditField.Value = fullfile(pname, fname);
                app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
            end
        end

        % Button pushed function: EmgDataSelectButton
        function EmgDataSelectButtonPushed(app, event)
            [fname, pname] = uigetfile('*.txt', 'Pick EMG Data txt File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.EMGDataTxtEditField.Value = fullfile(pname, fname);
                EMGDataTxtEditFieldValueChanged(app)
            end
        end

        % Value changed function: EMGDataTxtEditField
        function EMGDataTxtEditFieldValueChanged(app, event)
            value = app.EMGDataTxtEditField.Value;
            [session, muscle] = parse_fname_for_session_muscle(value);
            app.SessionEditField.Value = session;
            app.MuscleEditField.Value = muscle;
            app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
            
            app.emg_data = load(value);
            
            init_review_figs(app)
            
            app.RCDatapointsCSVEditField.Value = strrep(value, 'emg_data.txt', 'rc_datapoints.csv');
            RCDatapointsCSVEditFieldValueChanged(app)
            
            
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            value = app.SessionEditField.Value;
            app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
        end

        % Value changed function: RCDatapointsCSVEditField
        function RCDatapointsCSVEditFieldValueChanged(app, event)
            value = app.RCDatapointsCSVEditField.Value;
            % read in the rc datapoints table
            tbl = readtable(value);
            % initialize the datapoint uitable
            init_datapoint_table(app, tbl)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EMGDataTxtEditFieldLabel = uilabel(app.UIFigure);
            app.EMGDataTxtEditFieldLabel.Text = 'EMG Data Txt ';
            app.EMGDataTxtEditField = uieditfield(app.UIFigure, 'text');
            app.EMGDataTxtEditField.ValueChangedFcn = createCallbackFcn(app, @EMGDataTxtEditFieldValueChanged, true);
            app.EmgDataSelectButton = uibutton(app.UIFigure, 'push');
            app.EmgDataSelectButton.ButtonPushedFcn = createCallbackFcn(app, @EmgDataSelectButtonPushed, true);
            app.EmgDataSelectButton.Text = 'Select';
            app.MMaxSelectButton = uibutton(app.UIFigure, 'push');
            app.MMaxSelectButton.ButtonPushedFcn = createCallbackFcn(app, @MMaxSelectButtonPushed, true);
            app.MMaxSelectButton.Text = 'Select';
            app.MMaxExcelEditFieldLabel = uilabel(app.UIFigure);
            app.MMaxExcelEditFieldLabel.Text = 'M-Max Excel';
            app.MMaxExcelEditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.MuscleEditFieldLabel = uilabel(app.UIFigure);
            app.MuscleEditFieldLabel.Text = 'Muscle';
            app.MuscleEditField = uieditfield(app.UIFigure, 'text');
            app.MmaxVEditFieldLabel = uilabel(app.UIFigure);
            app.MmaxVEditFieldLabel.Text = 'M-max (µV)';
            app.MmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RCDatapointsCSVEditFieldLabel = uilabel(app.UIFigure);
            app.RCDatapointsCSVEditFieldLabel.Text = 'RC Datapoints CSV';
            app.RCDatapointsCSVEditField = uieditfield(app.UIFigure, 'text');
            app.RCDatapointsCSVEditField.ValueChangedFcn = createCallbackFcn(app, @RCDatapointsCSVEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = review_emg_rc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end